classdef AgentSmithApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        DataanalysisPanel     matlab.ui.container.Panel
        maxFreqEditField      matlab.ui.control.NumericEditField
        maxFreqkHzLabel       matlab.ui.control.Label
        maxLabel              matlab.ui.control.Label
        minFreqEditField      matlab.ui.control.NumericEditField
        minFreqkHzLabel       matlab.ui.control.Label
        minLabel              matlab.ui.control.Label
        FreqFilterCheckBox    matlab.ui.control.CheckBox
        smartselectXCheckBox  matlab.ui.control.CheckBox
        TestyButton           matlab.ui.control.Button
        LoadDataButton        matlab.ui.control.StateButton
        RightPanel            matlab.ui.container.Panel
        messageTextAreaLabel  matlab.ui.control.Label
        messageTextArea       matlab.ui.control.TextArea
        Display2Panel         matlab.ui.container.Panel
        DataCh4CheckBox_2     matlab.ui.control.CheckBox
        VoltageCheckBox_2     matlab.ui.control.CheckBox
        TriggerCheckBox_2     matlab.ui.control.CheckBox
        DataCh3CheckBox_2     matlab.ui.control.CheckBox
        DataCh2CheckBox_2     matlab.ui.control.CheckBox
        DataCh1CheckBox_2     matlab.ui.control.CheckBox
        Display1Panel         matlab.ui.container.Panel
        DataCh4CheckBox       matlab.ui.control.CheckBox
        VoltageCheckBox       matlab.ui.control.CheckBox
        TriggerCheckBox       matlab.ui.control.CheckBox
        DataCh3CheckBox       matlab.ui.control.CheckBox
        DataCh2CheckBox       matlab.ui.control.CheckBox
        DataCh1CheckBox       matlab.ui.control.CheckBox
        uiAxesDisp2           matlab.ui.control.UIAxes
        uiAxesDisp1           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data                % data array
        dataAna             % analysed data array
        t                   % time vector
        tUnit               % framerate
        ind1                % which channel to plot in disp 1
        ind2                % which channel to plot in disp 2
        xSmart              % enable smart x axis
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
            app.LoadDataButton.BackgroundColor = [.85 .33 .10];
            path = explorerFileName();
            app.messageTextArea.Value = ['...loading from: ', path];
            pause(0.2); 
            app.messageTextArea.Value = ['loaded from: ', path];
            app.LoadDataButton.BackgroundColor = [.47 .67 .19];
            app.LoadDataButton.Value = false;
        end

        % Button pushed function: TestyButton
        function TestyButtonPushed(app, event)
            path = 'D:\Fred\nEOdiag\matlab\neoAnalyser\file_000.dat';
            [app.data, app.t, app.tUnit, xFirst, xLast] = loadAndPrepData(path);
            app.xSmart(1) = app.smartselectXCheckBox.Value;
            app.xSmart(2) = xFirst;
            app.xSmart(3) = xLast;
            app.ind1 = checkBoxesSelected(app, 1);
            app.ind2 = checkBoxesSelected(app, 2);
            plotUIaxes(app.data, app.t, app.uiAxesDisp1, app.ind1, app.xSmart)
            plotUIaxes(app.data, app.t, app.uiAxesDisp2, app.ind2, app.xSmart)
        end

        % Value changed function: DataCh1CheckBox, DataCh2CheckBox, 
        % ...and 4 other components
        function DataCh1CheckBoxValueChanged(app, event)
            app.ind1 = checkBoxesSelected(app, 1);
            plotUIaxes(app.data, app.t, app.uiAxesDisp1, app.ind1, app.xSmart)
        end

        % Value changed function: DataCh1CheckBox_2, DataCh2CheckBox_2, 
        % ...and 4 other components
        function DataCh1CheckBox_2ValueChanged(app, event)
            app.ind2 = checkBoxesSelected(app, 2);
            plotUIaxes(app.data, app.t, app.uiAxesDisp2, app.ind2, app.xSmart)
        end

        % Value changed function: smartselectXCheckBox
        function smartselectXCheckBoxValueChanged(app, event)
            app.xSmart(1) = app.smartselectXCheckBox.Value;
            plotUIaxes(app.data, app.t, app.uiAxesDisp1, app.ind1, app.xSmart)
            plotUIaxes(app.data, app.t, app.uiAxesDisp2, app.ind2, app.xSmart)
        end

        % Value changed function: FreqFilterCheckBox
        function FreqFilterCheckBoxValueChanged(app, event)
            if app.FreqFilterCheckBox.Value == 0
                app.dataAna = freqFilter(app.data, app.minFreqEditField.Value, app.maxFreqEditField.Value, app.tUnit);
                plotUIaxes(app.dataAna, app.t, app.uiAxesDisp1, app.ind1, app.xSmart)
                plotUIaxes(app.dataAna, app.t, app.uiAxesDisp2, app.ind2, app.xSmart)
            else
                plotUIaxes(app.data, app.t, app.uiAxesDisp1, app.ind1, app.xSmart)
                plotUIaxes(app.data, app.t, app.uiAxesDisp2, app.ind2, app.xSmart)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {498, 498};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {215, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataButton = uibutton(app.LeftPanel, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TestyButton = uibutton(app.LeftPanel, 'push');
            app.TestyButton.ButtonPushedFcn = createCallbackFcn(app, @TestyButtonPushed, true);
            app.TestyButton.Text = 'Testy';
            app.smartselectXCheckBox = uicheckbox(app.LeftPanel);
            app.smartselectXCheckBox.ValueChangedFcn = createCallbackFcn(app, @smartselectXCheckBoxValueChanged, true);
            app.smartselectXCheckBox.Text = 'smart select X';
            app.smartselectXCheckBox.Value = true;
            app.DataanalysisPanel = uipanel(app.LeftPanel);
            app.DataanalysisPanel.Title = 'Data analysis';
            app.FreqFilterCheckBox = uicheckbox(app.DataanalysisPanel);
            app.FreqFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FreqFilterCheckBoxValueChanged, true);
            app.FreqFilterCheckBox.Text = 'FreqFilter';
            app.minLabel = uilabel(app.DataanalysisPanel);
            app.minLabel.Text = 'min';
            app.minFreqkHzLabel = uilabel(app.DataanalysisPanel);
            app.minFreqkHzLabel.Text = 'kHz';
            app.minFreqEditField = uieditfield(app.DataanalysisPanel, 'numeric');
            app.minFreqEditField.ValueDisplayFormat = '%.2f';
            app.maxLabel = uilabel(app.DataanalysisPanel);
            app.maxLabel.Text = 'max';
            app.maxFreqkHzLabel = uilabel(app.DataanalysisPanel);
            app.maxFreqkHzLabel.Text = 'kHz';
            app.maxFreqEditField = uieditfield(app.DataanalysisPanel, 'numeric');
            app.maxFreqEditField.ValueDisplayFormat = '%.2f';
            app.maxFreqEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.uiAxesDisp1 = uiaxes(app.RightPanel);
            title(app.uiAxesDisp1, 'Display 1')
            xlabel(app.uiAxesDisp1, 'time [s]')
            ylabel(app.uiAxesDisp1, 'voltage [mV]')
            zlabel(app.uiAxesDisp1, 'Z')
            app.uiAxesDisp2 = uiaxes(app.RightPanel);
            title(app.uiAxesDisp2, 'Display 2')
            xlabel(app.uiAxesDisp2, 'time [s]')
            ylabel(app.uiAxesDisp2, 'voltage [mV]')
            zlabel(app.uiAxesDisp2, 'Z')
            app.Display1Panel = uipanel(app.RightPanel);
            app.Display1Panel.Title = 'Display 1';
            app.DataCh1CheckBox = uicheckbox(app.Display1Panel);
            app.DataCh1CheckBox.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBoxValueChanged, true);
            app.DataCh1CheckBox.Text = 'Data Ch1';
            app.DataCh1CheckBox.Value = true;
            app.DataCh2CheckBox = uicheckbox(app.Display1Panel);
            app.DataCh2CheckBox.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBoxValueChanged, true);
            app.DataCh2CheckBox.Text = 'Data Ch2';
            app.DataCh2CheckBox.Value = true;
            app.DataCh3CheckBox = uicheckbox(app.Display1Panel);
            app.DataCh3CheckBox.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBoxValueChanged, true);
            app.DataCh3CheckBox.Text = 'Data Ch3';
            app.DataCh3CheckBox.Value = true;
            app.TriggerCheckBox = uicheckbox(app.Display1Panel);
            app.TriggerCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBoxValueChanged, true);
            app.TriggerCheckBox.Text = 'Trigger';
            app.VoltageCheckBox = uicheckbox(app.Display1Panel);
            app.VoltageCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBoxValueChanged, true);
            app.VoltageCheckBox.Text = 'Voltage';
            app.DataCh4CheckBox = uicheckbox(app.Display1Panel);
            app.DataCh4CheckBox.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBoxValueChanged, true);
            app.DataCh4CheckBox.Text = 'Data Ch4';
            app.DataCh4CheckBox.Value = true;
            app.Display2Panel = uipanel(app.RightPanel);
            app.Display2Panel.Title = 'Display 2';
            app.DataCh1CheckBox_2 = uicheckbox(app.Display2Panel);
            app.DataCh1CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBox_2ValueChanged, true);
            app.DataCh1CheckBox_2.Text = 'Data Ch1';
            app.DataCh2CheckBox_2 = uicheckbox(app.Display2Panel);
            app.DataCh2CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBox_2ValueChanged, true);
            app.DataCh2CheckBox_2.Text = 'Data Ch2';
            app.DataCh3CheckBox_2 = uicheckbox(app.Display2Panel);
            app.DataCh3CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBox_2ValueChanged, true);
            app.DataCh3CheckBox_2.Text = 'Data Ch3';
            app.TriggerCheckBox_2 = uicheckbox(app.Display2Panel);
            app.TriggerCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBox_2ValueChanged, true);
            app.TriggerCheckBox_2.Text = 'Trigger';
            app.TriggerCheckBox_2.Value = true;
            app.VoltageCheckBox_2 = uicheckbox(app.Display2Panel);
            app.VoltageCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBox_2ValueChanged, true);
            app.VoltageCheckBox_2.Text = 'Voltage';
            app.VoltageCheckBox_2.Value = true;
            app.DataCh4CheckBox_2 = uicheckbox(app.Display2Panel);
            app.DataCh4CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DataCh1CheckBox_2ValueChanged, true);
            app.DataCh4CheckBox_2.Text = 'Data Ch4';
            app.messageTextArea = uitextarea(app.RightPanel);
            app.messageTextAreaLabel = uilabel(app.RightPanel);
            app.messageTextAreaLabel.Text = 'report';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AgentSmithApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end